classdef Measurements < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        OutageTab             matlab.ui.container.Tab
        TimeScope             matlab.ui.scope.TimeScope
        DeratingTab           matlab.ui.container.Tab
        TimeScope_2           matlab.ui.scope.TimeScope
        BinningTab            matlab.ui.container.Tab
        TimeScope_3           matlab.ui.scope.TimeScope
        AnimationsPanel       matlab.ui.container.Panel
        WalkAwaynightButton   matlab.ui.control.StateButton
        WalkAwayDayButton     matlab.ui.control.StateButton
        AproachNightButton    matlab.ui.control.StateButton
        AproachDayButton      matlab.ui.control.StateButton
        MeasurementsPanel     matlab.ui.container.Panel
        VehiclesequencePanel  matlab.ui.container.Panel
        PresetTestButton      matlab.ui.control.StateButton
        NightButton           matlab.ui.control.StateButton
        DayButton             matlab.ui.control.StateButton
        BreakButton           matlab.ui.control.StateButton
        StartRunButton        matlab.ui.control.StateButton
        UnlockVehicleButton   matlab.ui.control.StateButton
        LockVehicleButton     matlab.ui.control.StateButton
        DigitalinputsPanel    matlab.ui.container.Panel
        Logic1Button_8        matlab.ui.control.StateButton
        Logic1Button_7        matlab.ui.control.StateButton
        Logic1Button_6        matlab.ui.control.StateButton
        Logic1Button_5        matlab.ui.control.StateButton
        Logic1Button_4        matlab.ui.control.StateButton
        Logic1Button_3        matlab.ui.control.StateButton
        Logic1Button_2        matlab.ui.control.StateButton
        Logic1Button          matlab.ui.control.StateButton
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
          AnLog1 ;
            AnLog2 ;
            AnLog3 ;
            AnLog4 ;
            
            %% Deigital 
            Log1 ;
            Log2 ;
            Log3 ;
            Log4 ;
            Log5 ;
            Log6 ;
            Log7 ;
            Log8 ;
            
            %% program setup 
            MLD ;
            Setup ;
            Lamp  ;
    end
    
    methods (Access = public)
        
        function results = prefillApp(app)
            app.Logic1Button.Text = app.Log1;
            app.Logic1Button_2.Text = app.Log2;
            app.Logic1Button_3.Text = app.Log3;
            app.Logic1Button_4.Text = app.Log4;
            app.Logic1Button_5.Text = app.Log5;
            app.Logic1Button_6.Text = app.Log6;
            app.Logic1Button_7.Text = app.Log7;
            app.Logic1Button_8.Text = app.Log8;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, testSetup)
            
            app.UIFigure.Name = 'Measurement';
            
            %% analog inputs
            app.AnLog1 =  testSetup.AnLog1;
            app.AnLog2 =  testSetup.AnLog2;
            app.AnLog3 =  testSetup.AnLog3;
            app.AnLog4 =  testSetup.AnLog4;
            
            %% Deigital inputs 
            app.Log1 = testSetup.Log1 ;
            app.Log2 = testSetup.Log2 ;
            app.Log3 = testSetup.Log3 ;
            app.Log4 = testSetup.Log4 ;
            app.Log5 = testSetup.Log5 ;
            app.Log6 = testSetup.Log6 ;
            app.Log7 = testSetup.Log7 ;
            app.Log8 = testSetup.Log8 ;
            
            %% program setup 
            app.MLD =   testSetup.MLD ;
            app.Setup = testSetup.Setup;
            app.Lamp =  testSetup.Lamp ;

            prefillApp(app);

            
        end

        % Value changed function: Logic1Button
        function Logic1ButtonValueChanged(app, event)
            value = app.Logic1Button.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MeasurementsPanel = uipanel(app.UIFigure);
            app.MeasurementsPanel.Title = 'Measurements ';
            app.DigitalinputsPanel = uipanel(app.MeasurementsPanel);
            app.DigitalinputsPanel.Title = 'Digital inputs ';
            app.Logic1Button = uibutton(app.DigitalinputsPanel, 'state');
            app.Logic1Button.ValueChangedFcn = createCallbackFcn(app, @Logic1ButtonValueChanged, true);
            app.Logic1Button.Text = 'Logic 1 ';
            app.Logic1Button_2 = uibutton(app.DigitalinputsPanel, 'state');
            app.Logic1Button_2.Text = 'Logic 1 ';
            app.Logic1Button_3 = uibutton(app.DigitalinputsPanel, 'state');
            app.Logic1Button_3.Text = 'Logic 1 ';
            app.Logic1Button_4 = uibutton(app.DigitalinputsPanel, 'state');
            app.Logic1Button_4.Text = 'Logic 1 ';
            app.Logic1Button_5 = uibutton(app.DigitalinputsPanel, 'state');
            app.Logic1Button_5.Text = 'Logic 1 ';
            app.Logic1Button_6 = uibutton(app.DigitalinputsPanel, 'state');
            app.Logic1Button_6.Text = 'Logic 1 ';
            app.Logic1Button_7 = uibutton(app.DigitalinputsPanel, 'state');
            app.Logic1Button_7.Text = 'Logic 1 ';
            app.Logic1Button_8 = uibutton(app.DigitalinputsPanel, 'state');
            app.Logic1Button_8.Text = 'Logic 1 ';
            app.VehiclesequencePanel = uipanel(app.MeasurementsPanel);
            app.VehiclesequencePanel.Title = 'Vehicle sequence ';
            app.LockVehicleButton = uibutton(app.VehiclesequencePanel, 'state');
            app.LockVehicleButton.Text = 'Lock Vehicle ';
            app.UnlockVehicleButton = uibutton(app.VehiclesequencePanel, 'state');
            app.UnlockVehicleButton.Text = 'Unlock Vehicle';
            app.StartRunButton = uibutton(app.VehiclesequencePanel, 'state');
            app.StartRunButton.Text = 'Start /Run';
            app.BreakButton = uibutton(app.VehiclesequencePanel, 'state');
            app.BreakButton.Text = 'Break';
            app.DayButton = uibutton(app.VehiclesequencePanel, 'state');
            app.DayButton.Text = 'Day';
            app.NightButton = uibutton(app.VehiclesequencePanel, 'state');
            app.NightButton.Text = 'Night';
            app.PresetTestButton = uibutton(app.VehiclesequencePanel, 'state');
            app.PresetTestButton.Text = 'Preset Test';
            app.AnimationsPanel = uipanel(app.UIFigure);
            app.AnimationsPanel.Title = 'Animations ';
            app.AproachDayButton = uibutton(app.AnimationsPanel, 'state');
            app.AproachDayButton.Text = 'Aproach Day';
            app.AproachNightButton = uibutton(app.AnimationsPanel, 'state');
            app.AproachNightButton.Text = 'Aproach Night';
            app.WalkAwayDayButton = uibutton(app.AnimationsPanel, 'state');
            app.WalkAwayDayButton.Text = 'WalkAway Day';
            app.WalkAwaynightButton = uibutton(app.AnimationsPanel, 'state');
            app.WalkAwaynightButton.Text = 'Walk Away night';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OutageTab = uitab(app.TabGroup);
            app.OutageTab.Title = 'Outage';
            app.TimeScope = uitimescope(app.OutageTab);
            app.TimeScope.Title = 'Title';
            app.TimeScope.YLabel = 'Amplitude';
            app.DeratingTab = uitab(app.TabGroup);
            app.DeratingTab.Title = 'Derating';
            app.TimeScope_2 = uitimescope(app.DeratingTab);
            app.TimeScope_2.Title = 'Title';
            app.TimeScope_2.YLabel = 'Amplitude';
            app.BinningTab = uitab(app.TabGroup);
            app.BinningTab.Title = 'Binning';
            app.TimeScope_3 = uitimescope(app.BinningTab);
            app.TimeScope_3.Title = 'Title';
            app.TimeScope_3.YLabel = 'Amplitude';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Measurements(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end