classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        VoltageLabel      matlab.ui.control.Label
        Lamp_2            matlab.ui.control.Lamp
        Label_4           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label             matlab.ui.control.Label
        Lamp              matlab.ui.control.Lamp
        TruthTableButton  matlab.ui.control.StateButton
        Logic4Button      matlab.ui.control.StateButton
        Logic3Button      matlab.ui.control.StateButton
        Logic2Button      matlab.ui.control.StateButton
        Logic1Button      matlab.ui.control.StateButton
        ConnectButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        device % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            port = "COM3"; % Replace or auto-detect
            app.device = serialport(port, 115200);
            app.Lamp.Color = 'green';
            app.Lamp_2.Color = 'white';
            while true
                if app.device.NumBytesAvailable >= 20  % Wait for 16 bytes (4 floats)
                rawData = read(app.device, 20, "uint8");                 % Read 16 bytes
                voltages = typecast(uint8(rawData), 'single');           % Convert bytes to floats
                flush(app.device)

                    % Update the labels
                    app.Label.Text   = ['Voltage: ', num2str(voltages(1), '%.3f'), ' V'];
                    app.Label_2.Text = ['Voltage: ', num2str(voltages(2), '%.3f'), ' V'];
                    app.Label_3.Text = ['Voltage: ', num2str(voltages(3), '%.3f'), ' V'];
                    app.Label_4.Text = ['Voltage: ', num2str(voltages(4), '%.3f'), ' V'];
                    app.VoltageLabel.Text = ['Voltage: ', num2str(voltages(5), '%.3f'), ' V'];
                    if voltages(5) > 2.000
                        app.Lamp_2.Color = 'green';
                    else
                        app.Lamp_2.Color = 'white';
                    end
                end
    
                    pause(0.05);  % Smaller pause for more responsive UI
            end
        end

        % Value changed function: Logic1Button
        function Logic1ButtonValueChanged(app, event)
            value = app.Logic1Button.Value;
            if value == 1
                writeline(app.device, "Logic1_ON");
            else
                writeline(app.device, "Logic1_OFF")
            end  
        end

        % Value changed function: Logic2Button
        function Logic2ButtonValueChanged(app, event)
            value = app.Logic2Button.Value;
            if value == 1
                writeline(app.device, "Logic2_ON");
            else
                writeline(app.device, "Logic2_OFF")
            end  
        end

        % Value changed function: Logic3Button
        function Logic3ButtonValueChanged(app, event)
            value = app.Logic3Button.Value;
            if value == 1
                writeline(app.device, "Logic3_ON");
            else
                writeline(app.device, "Logic3_OFF")
            end  
        end

        % Value changed function: Logic4Button
        function Logic4ButtonValueChanged(app, event)
            value = app.Logic4Button.Value;
            if value == 1
                writeline(app.device, "Logic4_ON");
            else
                writeline(app.device, "Logic4_OFF")
            end  
        end

        % Callback function
        function Logic5ButtonValueChanged(app, event)
            value = app.Logic5Button.Value;
            if value == 1
                writeline(app.device, "Logic5_ON");
            else
                writeline(app.device, "Logic5_OFF")
            end  
        end

        % Value changed function: TruthTableButton
        function TruthTableButtonValueChanged(app, event)
            value = app.TruthTableButton.Value;
            if value == 1
                writeline(app.device, "Truth_ON");
            else
                writeline(app.device, "Truth_OFF")
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Logic1Button = uibutton(app.UIFigure, 'state');
            app.Logic1Button.ValueChangedFcn = createCallbackFcn(app, @Logic1ButtonValueChanged, true);
            app.Logic1Button.Text = 'Logic 1';
            app.Logic2Button = uibutton(app.UIFigure, 'state');
            app.Logic2Button.ValueChangedFcn = createCallbackFcn(app, @Logic2ButtonValueChanged, true);
            app.Logic2Button.Text = 'Logic 2';
            app.Logic3Button = uibutton(app.UIFigure, 'state');
            app.Logic3Button.ValueChangedFcn = createCallbackFcn(app, @Logic3ButtonValueChanged, true);
            app.Logic3Button.Text = 'Logic 3';
            app.Logic4Button = uibutton(app.UIFigure, 'state');
            app.Logic4Button.ValueChangedFcn = createCallbackFcn(app, @Logic4ButtonValueChanged, true);
            app.Logic4Button.Text = 'Logic 4';
            app.TruthTableButton = uibutton(app.UIFigure, 'state');
            app.TruthTableButton.ValueChangedFcn = createCallbackFcn(app, @TruthTableButtonValueChanged, true);
            app.TruthTableButton.Text = 'Truth Table';
            app.Lamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '0';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '0';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '0';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.VoltageLabel = uilabel(app.UIFigure);
            app.VoltageLabel.Text = 'Voltage: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end