classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label_4           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label             matlab.ui.control.Label
        Lamp              matlab.ui.control.Lamp
        TruthTableButton  matlab.ui.control.StateButton
        Logic4Button      matlab.ui.control.StateButton
        Logic3Button      matlab.ui.control.StateButton
        Logic2Button      matlab.ui.control.StateButton
        Logic1Button      matlab.ui.control.StateButton
        ConnectButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        device % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
                port = "COM5"; % Replace or auto-detect
                app.device = serialport(port, 9600);
                app.Lamp.Color = 'green';
                pause(2);

                while true
                    if app.device.BytesAvailable > 0
                        % Read the voltage value as a string from Arduino
                        voltageData = fscanf(app.device, '%f,%f,%f,%f');
                        
                        % Update the label with the voltage value
                        app.Label.Text = ['Voltage: ', num2str(voltageData(1), '%.3f'), ' V'];
                        app.Label_2.Text = ['Voltage: ', num2str(voltageData(2), '%.3f'), ' V'];
                        app.Label_3.Text = ['Voltage: ', num2str(voltageData(3), '%.3f'), ' V'];
                        app.Label_4.Text = ['Voltage: ', num2str(voltageData(4), '%.3f'), ' V'];
                    end
                    
                    % Pause briefly to allow for smooth UI updates
                    pause(0.1);
                end
        end

        % Value changed function: Logic1Button
        function Logic1ButtonValueChanged(app, event)
            value = app.Logic1Button.Value;
            if value == 1
                writeline(app.device, "Logic1_ON");
            else
                writeline(app.device, "Logic1_OFF")
            end  
        end

        % Value changed function: Logic2Button
        function Logic2ButtonValueChanged(app, event)
            value = app.Logic2Button.Value;
            if value == 1
                writeline(app.device, "Logic2_ON");
            else
                writeline(app.device, "Logic2_OFF")
            end  
        end

        % Value changed function: Logic3Button
        function Logic3ButtonValueChanged(app, event)
            value = app.Logic3Button.Value;
            if value == 1
                writeline(app.device, "Logic3_ON");
            else
                writeline(app.device, "Logic3_OFF")
            end  
        end

        % Value changed function: Logic4Button
        function Logic4ButtonValueChanged(app, event)
            value = app.Logic4Button.Value;
            if value == 1
                writeline(app.device, "Logic4_ON");
            else
                writeline(app.device, "Logic4_OFF")
            end  
        end

        % Callback function: not associated with a component
        function Logic5ButtonValueChanged(app, event)
            value = app.Logic5Button.Value;
            if value == 1
                writeline(app.device, "Logic5_ON");
            else
                writeline(app.device, "Logic5_OFF")
            end  
        end

        % Value changed function: TruthTableButton
        function TruthTableButtonValueChanged(app, event)
            value = app.TruthTableButton.Value;
            if value == 1
                writeline(app.device, "Truth_ON");
            else
                writeline(app.device, "Truth_OFF")
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Logic1Button = uibutton(app.UIFigure, 'state');
            app.Logic1Button.ValueChangedFcn = createCallbackFcn(app, @Logic1ButtonValueChanged, true);
            app.Logic1Button.Text = 'Logic 1';
            app.Logic2Button = uibutton(app.UIFigure, 'state');
            app.Logic2Button.ValueChangedFcn = createCallbackFcn(app, @Logic2ButtonValueChanged, true);
            app.Logic2Button.Text = 'Logic 2';
            app.Logic3Button = uibutton(app.UIFigure, 'state');
            app.Logic3Button.ValueChangedFcn = createCallbackFcn(app, @Logic3ButtonValueChanged, true);
            app.Logic3Button.Text = 'Logic 3';
            app.Logic4Button = uibutton(app.UIFigure, 'state');
            app.Logic4Button.ValueChangedFcn = createCallbackFcn(app, @Logic4ButtonValueChanged, true);
            app.Logic4Button.Text = 'Logic 4';
            app.TruthTableButton = uibutton(app.UIFigure, 'state');
            app.TruthTableButton.ValueChangedFcn = createCallbackFcn(app, @TruthTableButtonValueChanged, true);
            app.TruthTableButton.Text = 'Truth Table';
            app.Lamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '0';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '0';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end