classdef TestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ForviaHellaPanel         matlab.ui.container.Panel
        StarttestLabel           matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        LoadLabel                matlab.ui.control.Label
        SaveLabel                matlab.ui.control.Label
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        ProgramInformationPanel  matlab.ui.container.Panel
        MLDDropDown              matlab.ui.control.DropDown
        MLDDropDownLabel         matlab.ui.control.Label
        SetupDropDown            matlab.ui.control.DropDown
        SetupDropDownLabel       matlab.ui.control.Label
        LampTypeDropDown         matlab.ui.control.DropDown
        LampTypeDropDownLabel    matlab.ui.control.Label
        AnalogInputPanel         matlab.ui.container.Panel
        Analog3DropDown          matlab.ui.control.DropDown
        Analog3DropDownLabel     matlab.ui.control.Label
        Analog2DropDown          matlab.ui.control.DropDown
        Analog2DropDownLabel     matlab.ui.control.Label
        Analog4DropDown          matlab.ui.control.DropDown
        Analog4DropDownLabel     matlab.ui.control.Label
        Analog1DropDown          matlab.ui.control.DropDown
        Analog1DropDownLabel     matlab.ui.control.Label
        DigitalInputsPanel       matlab.ui.container.Panel
        Logic1DropDown           matlab.ui.control.DropDown
        Logic1DropDownLabel      matlab.ui.control.Label
        Logic8DropDown           matlab.ui.control.DropDown
        Logic8DropDownLabel      matlab.ui.control.Label
        Logic7DropDown           matlab.ui.control.DropDown
        Logic7DropDownLabel      matlab.ui.control.Label
        Logic6DropDown           matlab.ui.control.DropDown
        Logic6DropDownLabel      matlab.ui.control.Label
        Logic5DropDown           matlab.ui.control.DropDown
        Logic5DropDownLabel      matlab.ui.control.Label
        Logic4DropDown           matlab.ui.control.DropDown
        Logic4DropDownLabel      matlab.ui.control.Label
        Logic3DropDown           matlab.ui.control.DropDown
        Logic3DropDownLabel      matlab.ui.control.Label
        Logic2DropDown           matlab.ui.control.DropDown
        Logic2DropDownLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'MLD 1.1 Test APP';
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            
            %% analog inputs 
            testSetup.AnLog1 =  app.Analog1DropDown.Value;
            testSetup.AnLog2 =  app.Analog2DropDown.Value;
            testSetup.AnLog3 =  app.Analog3DropDown.Value;
            testSetup.AnLog4 =  app.Analog4DropDown.Value;

            %% Deigital inputs 
            testSetup.Log1 = app.Logic1DropDown.Value;
            testSetup.Log2 = app.Logic2DropDown.Value;
            testSetup.Log3 = app.Logic3DropDown.Value;
            testSetup.Log4 = app.Logic4DropDown.Value;
            testSetup.Log5 = app.Logic5DropDown.Value;
            testSetup.Log6 = app.Logic6DropDown.Value;
            testSetup.Log7 = app.Logic7DropDown.Value;
            testSetup.Log8 = app.Logic8DropDown.Value;
            
            %% program setup 
            testSetup.MLD = app.MLDDropDown.Value;
            testSetup.Setup = app.SetupDropDown.Value;
            testSetup.Lamp = app.LampTypeDropDown.Value;

            app.measurementapp = Measurements(testSetup);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ForviaHellaPanel = uipanel(app.UIFigure);
            app.ForviaHellaPanel.Title = 'Forvia Hella ';
            app.DigitalInputsPanel = uipanel(app.ForviaHellaPanel);
            app.DigitalInputsPanel.Title = 'Digital Inputs ';
            app.Logic2DropDownLabel = uilabel(app.DigitalInputsPanel);
            app.Logic2DropDownLabel.Text = 'Logic  2';
            app.Logic2DropDown = uidropdown(app.DigitalInputsPanel);
            app.Logic2DropDown.Items = {'HB', 'LB', 'Turn', 'Turn Seq', 'Animation ', 'DRL / PL'};
            app.Logic2DropDown.Value = 'Turn Seq';
            app.Logic3DropDownLabel = uilabel(app.DigitalInputsPanel);
            app.Logic3DropDownLabel.Text = 'Logic 3';
            app.Logic3DropDown = uidropdown(app.DigitalInputsPanel);
            app.Logic3DropDown.Items = {'HB', 'LB', 'Turn', 'Not used ', 'Turn Seq', 'Animation ', 'DRL / PL'};
            app.Logic3DropDown.Value = 'Not used ';
            app.Logic4DropDownLabel = uilabel(app.DigitalInputsPanel);
            app.Logic4DropDownLabel.Text = 'Logic 4';
            app.Logic4DropDown = uidropdown(app.DigitalInputsPanel);
            app.Logic4DropDown.Items = {'HB', 'LB', 'Turn', 'Turn Seq', 'Animation ', 'DRL / PL'};
            app.Logic4DropDown.Value = 'HB';
            app.Logic5DropDownLabel = uilabel(app.DigitalInputsPanel);
            app.Logic5DropDownLabel.Text = 'Logic 5';
            app.Logic5DropDown = uidropdown(app.DigitalInputsPanel);
            app.Logic5DropDown.Items = {'HB', 'LB', 'Turn', 'Turn Seq', 'Animation ', 'DRL / PL'};
            app.Logic5DropDown.Value = 'Animation ';
            app.Logic6DropDownLabel = uilabel(app.DigitalInputsPanel);
            app.Logic6DropDownLabel.Text = 'Logic 6';
            app.Logic6DropDown = uidropdown(app.DigitalInputsPanel);
            app.Logic6DropDown.Items = {'HB', 'LB', 'Turn', 'Turn Seq', 'Animation ', 'DRL / PL', 'Not used '};
            app.Logic6DropDown.Value = 'Not used ';
            app.Logic7DropDownLabel = uilabel(app.DigitalInputsPanel);
            app.Logic7DropDownLabel.Text = 'Logic 7';
            app.Logic7DropDown = uidropdown(app.DigitalInputsPanel);
            app.Logic7DropDown.Items = {'HB', 'LB', 'Turn', 'Turn Seq', 'Animation ', 'DRL / PL'};
            app.Logic7DropDown.Value = 'Turn';
            app.Logic8DropDownLabel = uilabel(app.DigitalInputsPanel);
            app.Logic8DropDownLabel.Text = 'Logic 8';
            app.Logic8DropDown = uidropdown(app.DigitalInputsPanel);
            app.Logic8DropDown.Items = {'HB', 'LB', 'Turn', 'Turn Seq', 'Animation ', 'DRL / PL', 'Not used '};
            app.Logic8DropDown.Value = 'Not used ';
            app.Logic1DropDownLabel = uilabel(app.DigitalInputsPanel);
            app.Logic1DropDownLabel.Text = 'Logic 1 ';
            app.Logic1DropDown = uidropdown(app.DigitalInputsPanel);
            app.Logic1DropDown.Items = {'HB', 'LB', 'Turn', 'Turn Seq', 'Animation ', 'DRL / PL'};
            app.Logic1DropDown.Value = 'LB';
            app.AnalogInputPanel = uipanel(app.ForviaHellaPanel);
            app.AnalogInputPanel.Title = 'Analog Input';
            app.Analog1DropDownLabel = uilabel(app.AnalogInputPanel);
            app.Analog1DropDownLabel.Text = 'Analog 1';
            app.Analog1DropDown = uidropdown(app.AnalogInputPanel);
            app.Analog1DropDown.Items = {'NTC 1', 'BIN 1'};
            app.Analog1DropDown.Value = 'NTC 1';
            app.Analog4DropDownLabel = uilabel(app.AnalogInputPanel);
            app.Analog4DropDownLabel.Text = 'Analog 4';
            app.Analog4DropDown = uidropdown(app.AnalogInputPanel);
            app.Analog4DropDown.Items = {'NTC 1', 'BIN 1'};
            app.Analog4DropDown.Value = 'NTC 1';
            app.Analog2DropDownLabel = uilabel(app.AnalogInputPanel);
            app.Analog2DropDownLabel.Text = 'Analog 2';
            app.Analog2DropDown = uidropdown(app.AnalogInputPanel);
            app.Analog2DropDown.Items = {'NTC 1', 'BIN 1'};
            app.Analog2DropDown.Value = 'NTC 1';
            app.Analog3DropDownLabel = uilabel(app.AnalogInputPanel);
            app.Analog3DropDownLabel.Text = 'Analog 3';
            app.Analog3DropDown = uidropdown(app.AnalogInputPanel);
            app.Analog3DropDown.Items = {'NTC 1', 'BIN 1'};
            app.Analog3DropDown.Value = 'NTC 1';
            app.ProgramInformationPanel = uipanel(app.ForviaHellaPanel);
            app.ProgramInformationPanel.Title = 'Program Information';
            app.LampTypeDropDownLabel = uilabel(app.ProgramInformationPanel);
            app.LampTypeDropDownLabel.Text = 'LampType';
            app.LampTypeDropDown = uidropdown(app.ProgramInformationPanel);
            app.LampTypeDropDown.Items = {'Head Lamp', 'Tail Lamp', 'Body', 'Option 4'};
            app.LampTypeDropDown.Value = 'Head Lamp';
            app.SetupDropDownLabel = uilabel(app.ProgramInformationPanel);
            app.SetupDropDownLabel.Text = 'Setup ';
            app.SetupDropDown = uidropdown(app.ProgramInformationPanel);
            app.SetupDropDown.Items = {'Full Test ', 'Demo'};
            app.SetupDropDown.Value = 'Full Test ';
            app.MLDDropDownLabel = uilabel(app.ProgramInformationPanel);
            app.MLDDropDownLabel.Text = 'MLD';
            app.MLDDropDown = uidropdown(app.ProgramInformationPanel);
            app.MLDDropDown.Items = {'MINI', 'STD', 'PLUS', 'Option 4'};
            app.MLDDropDown.Value = 'MINI';
            app.Button = uibutton(app.ForviaHellaPanel, 'push');
            app.Button.Icon = fullfile(pathToMLAPP, 'save.png');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.ForviaHellaPanel, 'push');
            app.Button_2.Icon = fullfile(pathToMLAPP, 'file.png');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.ForviaHellaPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'play.png');
            app.Button_3.Text = '';
            app.SaveLabel = uilabel(app.ForviaHellaPanel);
            app.SaveLabel.Text = 'Save';
            app.LoadLabel = uilabel(app.ForviaHellaPanel);
            app.LoadLabel.Text = 'Load';
            app.Image = uiimage(app.ForviaHellaPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'forvia.png');
            app.StarttestLabel = uilabel(app.ForviaHellaPanel);
            app.StarttestLabel.Text = 'Start test ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end